classdef EV_testbed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToocloseLampLabel           matlab.ui.control.Label
        ToocloseLamp                matlab.ui.control.Lamp
        HeadlightStatusLampLabel    matlab.ui.control.Label
        HeadlightStatusLamp         matlab.ui.control.Lamp
        LowcoolantLampLabel         matlab.ui.control.Label
        LowcoolantLamp              matlab.ui.control.Lamp
        MotorspeedGaugeLabel        matlab.ui.control.Label
        MotorspeedGauge             matlab.ui.control.SemicircularGauge
        StartButton                 matlab.ui.control.Button
        DistancemEditFieldLabel     matlab.ui.control.Label
        DistancemEditField          matlab.ui.control.NumericEditField
        EndButton                   matlab.ui.control.Button
        ArduinoConnectionLampLabel  matlab.ui.control.Label
        ArduinoConnectionLamp       matlab.ui.control.Lamp
        ConnErrorEditFieldLabel     matlab.ui.control.Label
        ConnErrorEditField          matlab.ui.control.EditField
        ToggleHeadlightButton       matlab.ui.control.Button
        ToggleLowcoolantButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduino
        isArduinoConnected
        distance
        coolantLevel
        closeness
        headlightState
        coolantState
        speed
        ctrl
    end
    
    methods (Access = private)
        
        function initializeAppStates(app)
            app.isArduinoConnected = false;
            app.distance = 3000;        % initialized to very far
            app.coolantLevel = 100;
            app.closeness = 0;          % 0=far, 1=close, 2=very close, 3=danger
            app.headlightState = false; % 0=off, 1=dim, 2=on
            app.coolantState = false;   % 0=full, 1=empty
            app.speed = 0;              % motor speed
            app.ctrl = 0;               % 0=auto, 1=manual
        end
        
        function connectToArduino(app)
            try
                info = instrhwinfo('serial');
                serial_port = info.SerialPorts(2);
                % Open a serial connection to arduino
                app.arduino = serial(serial_port{1},'BaudRate',9600);
                fopen(app.arduino);
                app.isArduinoConnected = true;
                app.ConnErrorEditField.Value = '';
            catch e
                fprintf('cant open\n');
                app.ConnErrorEditField.Value = [e.identifier, ': ', e.message];
                try
                    fclose(app.arduino);
                catch
                    fprintf('cant close\n');
                end
            end
        end
        
        function updateAppStates(app)
            app.DistancemEditField.Value = app.distance;
            
            if app.closeness == 3
                app.ToocloseLamp.Color = [1,0,0];
            elseif app.closeness == 2
                app.ToocloseLamp.Color = [0,0,1];
            elseif app.closeness == 1
                app.ToocloseLamp.Color = [0,1,0];
            else
                app.ToocloseLamp.Color = [0,0,0];
            end
            
            if app.headlightState == 2
                app.HeadlightStatusLamp.Color = [0,0,1];
            elseif app.headlightState == 1
                app.HeadlightStatusLamp.Color = [0,1,0];
            else
                app.HeadlightStatusLamp.Color = [0,0,0];
            end
            
            if app.coolantLevel < 10
                app.LowcoolantLamp.Color = [0,1,0];
            else
                app.LowcoolantLamp.Color = [1,0,0];
            end
            
            app.MotorspeedGauge.Value = app.speed;
            
        end
        
        function sendCommandToArduino(app)
            scpi_str = ":SET:ALL ";
            if app.closeness == 3           % danger
                scpi_str = scpi_str + "0,0";
            elseif app.closeness == 2       % very close
                angle = uint8(app.distance/20*120);
                scpi_str = scpi_str + "1," + num2str(angle);
            elseif app.closeness == 1       % close
                angle = uint8(app.distance/30*180);
                scpi_str = scpi_str + "2," + num2str(angle);
            else
                scpi_str = scpi_str + "3,180";
            end
            
            if app.ctrl == 0
                scpi_str = scpi_str + "," + num2str(uint8(app.speed));
            end
            scpi_str = scpi_str + ",";
            fprintf(app.arduino, scpi_str);
            flushinput(app.arduino);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = false;
            app.EndButton.Visible = true;
            initializeAppStates(app);
            connectToArduino(app);
            if app.isArduinoConnected
                app.ArduinoConnectionLamp.Color = [0,1,0];
            else
                app.ArduinoConnectionLamp.Color = [1,0,0];
            end
            
            pause(0.1)

            num_timeout = 0;
            while app.isArduinoConnected && num_timeout < 6
                flushinput(app.arduino);
                fprintf(app.arduino, ':MEAS:ALL');
                pause(0.01)
                serial_str = fscanf(app.arduino);
                serial_out = strsplit(serial_str, ' ');
                
                if length(serial_out) < 3
                    num_timeout = num_timeout + 1;
                    continue;
                end
                
                app.distance = str2double(serial_out(1))/(29.1*2);
                app.coolantLevel = uint8(str2double(serial_out(2)));
                pause(0.01)
                
                flushinput(app.arduino);
                fprintf(app.arduino, ':GET:ALL');
                pause(0.01)
                serial_str = fscanf(app.arduino);
                serial_out = strsplit(serial_str, ' ');
                
                if length(serial_out) < 3
                    num_timeout = num_timeout + 1;
                    continue;
                end
                
                app.ctrl = uint8(str2double(serial_out(1)));
                app.speed = uint8(str2double(serial_out(2)));
                app.headlightState = uint8(str2double(serial_out(3)));
                pause(0.01)
                
                if app.distance < 10
                    app.closeness = 3;
                elseif app.distance < 20
                    app.closeness = 2;
                elseif app.distance < 30
                    app.closeness = 1;
                else
                    app.closeness = 0;
                end
                
                if app.ctrl == 0
                    if app.distance < 10
                        app.speed = 0;
                    elseif app.distance < 20
                        app.speed = app.distance/20*50;
                    elseif app.distance < 30
                        app.speed = app.distance/30*100;
                    else
                        app.speed = 100;
                    end
                end
                
                updateAppStates(app)
                sendCommandToArduino(app)
                pause(0.1)
            end
            
            if num_timeout == 5
                app.ConnErrorEditField.Value = 'Serial read timeout > 5 times';
            end
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.StartButton.Visible = true;
            app.EndButton.Visible = false;
            app.ConnErrorEditField.Value = '';
            app.isArduinoConnected = false;
            app.ArduinoConnectionLamp.Color = [1,0,0];
            try
                fclose(app.arduino);
            catch
                fprintf('cant close\n');
            end
            
            delete(app.arduino);
        end

        % Button pushed function: ToggleHeadlightButton
        function ToggleHeadlightButtonPushed(app, event)
            if app.headlightState
                app.headlightState = false;
            else
                app.headlightState = true;
            end
            
            if app.headlightState
                app.HeadlightStatusLamp.Color = [0,1,0];
            else
                app.HeadlightStatusLamp.Color = [1,0,0];
            end
            drawnow
        end

        % Button pushed function: ToggleLowcoolantButton
        function ToggleLowcoolantButtonPushed(app, event)
            if app.coolantState
                app.coolantState = false;
            else
                app.coolantState = true;
            end
            
            if app.coolantState
                app.LowcoolantLamp.Color = [0,1,0];
            else
                app.LowcoolantLamp.Color = [1,0,0];
            end
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ToocloseLampLabel = uilabel(app.UIFigure);
            app.ToocloseLampLabel.Text = 'Too close?';
            app.ToocloseLamp = uilamp(app.UIFigure);
            app.HeadlightStatusLampLabel = uilabel(app.UIFigure);
            app.HeadlightStatusLampLabel.Text = 'Headlight Status';
            app.HeadlightStatusLamp = uilamp(app.UIFigure);
            app.HeadlightStatusLamp.Tooltip = {'Red = OFF'; 'Yellow = DIM'; 'Green = ON'};
            app.LowcoolantLampLabel = uilabel(app.UIFigure);
            app.LowcoolantLampLabel.Text = 'Low coolant';
            app.LowcoolantLamp = uilamp(app.UIFigure);
            app.MotorspeedGaugeLabel = uilabel(app.UIFigure);
            app.MotorspeedGaugeLabel.Text = 'Motor speed (%)';
            app.MotorspeedGauge = uigauge(app.UIFigure, 'semicircular');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DistancemEditFieldLabel = uilabel(app.UIFigure);
            app.DistancemEditFieldLabel.Text = 'Distance (m)';
            app.DistancemEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancemEditField.Editable = 'off';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.ArduinoConnectionLampLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionLampLabel.Text = 'Arduino Connection';
            app.ArduinoConnectionLamp = uilamp(app.UIFigure);
            app.ConnErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ConnErrorEditFieldLabel.Text = 'ConnError';
            app.ConnErrorEditField = uieditfield(app.UIFigure, 'text');
            app.ConnErrorEditField.Editable = 'off';
            app.ToggleHeadlightButton = uibutton(app.UIFigure, 'push');
            app.ToggleHeadlightButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleHeadlightButtonPushed, true);
            app.ToggleHeadlightButton.Text = 'Toggle Headlight';
            app.ToggleLowcoolantButton = uibutton(app.UIFigure, 'push');
            app.ToggleLowcoolantButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleLowcoolantButtonPushed, true);
            app.ToggleLowcoolantButton.Text = 'Toggle Low coolant';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EV_testbed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end